classdef LoadImageFromUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        BrowseImageLabel           matlab.ui.control.Label
        BrowseButton               matlab.ui.control.Button
        SelectFilterDropDownLabel  matlab.ui.control.Label
        SelectFilterDropDown       matlab.ui.control.DropDown
        ApplyFilterButton          matlab.ui.control.Button
        OriginalImage              matlab.ui.control.UIAxes
        FilteredImage              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global img;
            [file_name, path_name] = uigetfile({'*.jpg;*.png'}, 'File Selector');
            full_pat_hname = strcat(path_name,file_name);
            img = imread(full_pat_hname);
            original_img = img;
            [y, x, channels] = size(img);
            if channels > 1
                img = rgb2gray(img);
                img = im2bw(img);
            elseif channels == 1
                img = im2bw(img);
            end
            % imshow(original_img,'Parent',app.OriginalImage);
            imshow(original_img,'Parent',app.OriginalImage);
        end

        % Value changed function: SelectFilterDropDown
        function SelectFilterDropDownValueChanged(app, event)
            global dropdown_value;
            dropdown_value = app.SelectFilterDropDown.Value;
            
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            global img;
            global dropdown_value;
            
            if strcmp(dropdown_value,'Bottom Hat')
                img2 = bwmorph(img, 'bothat');
                imshow(img2,'Parent', app.FilteredImage);
                
            elseif strcmp(dropdown_value,'Clean')
                img2 = bwmorph(img,'clean');
                imshow(img2,'Parent', app.FilteredImage);
                
            elseif strcmp(dropdown_value,'Close')
                img2 = bwmorph(img,'close');
                imshow(img2,'Parent', app.FilteredImage);
                
            elseif strcmp(dropdown_value,'Remove')
                img2 = bwmorph(img,'remove');
                imshow(img2,'Parent', app.FilteredImage);
                
            elseif strcmp(dropdown_value,'Skel')
                img2 = bwmorph(img,'skel',Inf);
                imshow(img2,'Parent', app.FilteredImage);
            
            elseif strcmp(dropdown_value,'Thin')
                img2 = bwmorph(img,'thin');
                imshow(img2,'Parent', app.FilteredImage);
                
            elseif strcmp(dropdown_value,'Open')
                img2 = bwmorph(img,'open');
                imshow(img2,'Parent', app.FilteredImage);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Logo-01.png';
            app.BrowseImageLabel = uilabel(app.UIFigure);
            app.BrowseImageLabel.Text = 'Browse Image';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectFilterDropDownLabel = uilabel(app.UIFigure);
            app.SelectFilterDropDownLabel.Text = 'Select Filter';
            app.SelectFilterDropDown = uidropdown(app.UIFigure);
            app.SelectFilterDropDown.Items = {'Bottom Hat', 'Clean', 'Close', 'Remove', 'Thin', 'Skel', 'Open'};
            app.SelectFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFilterDropDownValueChanged, true);
            app.SelectFilterDropDown.Value = 'Bottom Hat';
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.OriginalImage = uiaxes(app.UIFigure);
            title(app.OriginalImage, 'Original Image')
            app.FilteredImage = uiaxes(app.UIFigure);
            title(app.FilteredImage, 'Filtered Image')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadImageFromUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end